classdef PlaneStress < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SolveButton                    matlab.ui.control.Button
        ProcessingLabel                matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        BoundaryConditionsButtonGroup  matlab.ui.container.ButtonGroup
        ForceButton                    matlab.ui.control.ToggleButton
        DisplacementButton             matlab.ui.control.ToggleButton
        MeshOptionsLabel               matlab.ui.control.Label
        PlaneDimensionsLabel           matlab.ui.control.Label
        VerticalDivisionsEditField     matlab.ui.control.NumericEditField
        VerticalDivisionsEditFieldLabel  matlab.ui.control.Label
        HorizontalDivisionsEditField   matlab.ui.control.NumericEditField
        HorizontalDivisionsEditFieldLabel  matlab.ui.control.Label
        HeightEditField                matlab.ui.control.NumericEditField
        HeightEditFieldLabel           matlab.ui.control.Label
        WidthEditField_2               matlab.ui.control.NumericEditField
        WidthEditField_2Label          matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        NodeCoordinates % Description
        ElementNodes % Description
        CornerNodes % Description
        SideNodes % Description
        Displacements % Description
        Forces
    end

    methods (Access = private)

        function results = graph(app)
            if app.WidthEditField_2.Value~=0 && app.HeightEditField.Value~=0
                [p,cl]=GenerateMesh(app.WidthEditField_2.Value,app.HeightEditField.Value,app.HorizontalDivisionsEditField.Value,app.VerticalDivisionsEditField.Value);
                hold(app.UIAxes, 'all');
                patch(app.UIAxes,'faces', cl, 'Vertices', p, 'facecolor', 'c', 'edgecolor', 'k');
                plot(app.UIAxes,p(1,:), p(2,:), 'o', 'color', 'k');
                app.NodeCoordinates=p;
                app.ElementNodes=cl;
                %% Finding corner nodes
                app.CornerNodes=[1,
                    1+app.HorizontalDivisionsEditField.Value,
                    (1+app.HorizontalDivisionsEditField.Value)*(app.VerticalDivisionsEditField.Value+1),
                    (1+app.HorizontalDivisionsEditField.Value)*app.VerticalDivisionsEditField.Value+1];
                %End of finding corner nodes

                %% Finding Side nodes
                app.SideNodes=zeros(4,max([app.VerticalDivisionsEditField.Value+1,app.HorizontalDivisionsEditField.Value+1]));


                app.SideNodes(1,1:app.HorizontalDivisionsEditField.Value+1)=[1:(app.HorizontalDivisionsEditField.Value+1)];
                app.SideNodes(2,1:app.VerticalDivisionsEditField.Value+1)=[(1:app.VerticalDivisionsEditField.Value+1).*(app.HorizontalDivisionsEditField.Value+1)];
                app.SideNodes(3,1:app.HorizontalDivisionsEditField.Value+1)=[(1+app.HorizontalDivisionsEditField.Value)*app.VerticalDivisionsEditField.Value+1:(1+app.HorizontalDivisionsEditField.Value)*(app.VerticalDivisionsEditField.Value+1)];
                app.SideNodes(4,1:app.VerticalDivisionsEditField.Value+1)=[(1:app.VerticalDivisionsEditField.Value+1).*(app.HorizontalDivisionsEditField.Value+1)-app.HorizontalDivisionsEditField.Value];

                %End of Finding Side nodes

            end


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ProcessingLabel.Text="Processing...";
            app.Displacements=NaN(8,2);
            app.Forces=zeros(8,2);
            app.UITable.Data=app.Displacements;
            app.ProcessingLabel.Text="Ready for inputs";
        end

        % Value changed function: WidthEditField_2
        function WidthEditField_2ValueChanged(app, event)
            value = app.WidthEditField_2.Value;
            app.graph();
        end

        % Value changed function: HeightEditField
        function HeightEditFieldValueChanged(app, event)
            value = app.HeightEditField.Value;
            app.graph();
        end

        % Value changed function: HorizontalDivisionsEditField
        function HorizontalDivisionsEditFieldValueChanged(app, event)
            value = app.HorizontalDivisionsEditField.Value;
            app.graph();
        end

        % Value changed function: VerticalDivisionsEditField
        function VerticalDivisionsEditFieldValueChanged(app, event)
            value = app.VerticalDivisionsEditField.Value;
            app.graph();
        end

        % Selection changed function: BoundaryConditionsButtonGroup
        function BoundaryConditionsButtonGroupSelectionChanged(app, event)
            selectedButton = app.BoundaryConditionsButtonGroup.SelectedObject;

            if selectedButton.Text=="Displacement"
                app.UITable.Data=app.Displacements;
            elseif selectedButton.Text=="Force"
                app.UITable.Data=app.Forces;
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            selectedButton = app.BoundaryConditionsButtonGroup.SelectedObject;
            if selectedButton.Text=="Displacement"
                app.Displacements=app.UITable.Data;
            elseif selectedButton.Text=="Force"
                app.Forces=app.UITable.Data;
            end

        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.WidthEditField_2Label = uilabel(app.UIFigure);
            app.WidthEditField_2Label.Text = 'Width';
            app.WidthEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.WidthEditField_2.ValueChangedFcn = createCallbackFcn(app, @WidthEditField_2ValueChanged, true);
            app.HeightEditFieldLabel = uilabel(app.UIFigure);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightEditField.ValueChangedFcn = createCallbackFcn(app, @HeightEditFieldValueChanged, true);
            app.HorizontalDivisionsEditFieldLabel = uilabel(app.UIFigure);
            app.HorizontalDivisionsEditFieldLabel.Text = 'Horizontal Divisions';
            app.HorizontalDivisionsEditField = uieditfield(app.UIFigure, 'numeric');
            app.HorizontalDivisionsEditField.ValueChangedFcn = createCallbackFcn(app, @HorizontalDivisionsEditFieldValueChanged, true);
            app.HorizontalDivisionsEditField.Value = 1;
            app.VerticalDivisionsEditFieldLabel = uilabel(app.UIFigure);
            app.VerticalDivisionsEditFieldLabel.Text = 'Vertical Divisions';
            app.VerticalDivisionsEditField = uieditfield(app.UIFigure, 'numeric');
            app.VerticalDivisionsEditField.ValueChangedFcn = createCallbackFcn(app, @VerticalDivisionsEditFieldValueChanged, true);
            app.VerticalDivisionsEditField.Value = 1;
            app.PlaneDimensionsLabel = uilabel(app.UIFigure);
            app.PlaneDimensionsLabel.Text = 'Plane Dimensions';
            app.MeshOptionsLabel = uilabel(app.UIFigure);
            app.MeshOptionsLabel.Text = 'Mesh Options';
            app.BoundaryConditionsButtonGroup = uibuttongroup(app.UIFigure);
            app.BoundaryConditionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BoundaryConditionsButtonGroupSelectionChanged, true);
            app.BoundaryConditionsButtonGroup.Title = 'Boundary Conditions';
            app.DisplacementButton = uitogglebutton(app.BoundaryConditionsButtonGroup);
            app.DisplacementButton.Text = 'Displacement';
            app.DisplacementButton.Value = true;
            app.ForceButton = uitogglebutton(app.BoundaryConditionsButtonGroup);
            app.ForceButton.Text = 'Force';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'x'; 'y'};
            app.UITable.RowName = {'Node 1'; 'Node 2'; 'Node 3'; 'Node 4'; 'Side 1'; 'Side 2'; 'Side 3'; 'Side 4'};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.ProcessingLabel = uilabel(app.UIFigure);
            app.ProcessingLabel.Text = 'Processing';
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlaneStress

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end